classdef CIE_337_Project_II_Part_A < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EntertheTimeVectorEditFieldLabel  matlab.ui.control.Label
        EntertheTimeVectorEditField     matlab.ui.control.EditField
        EntertheMessageSignalEditFieldLabel  matlab.ui.control.Label
        EntertheMessageSignalEditField  matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        Label                           matlab.ui.control.Label
        SelectaQuantizationTechniqueDropDownLabel  matlab.ui.control.Label
        SelectaQuantizationTechniqueDropDown  matlab.ui.control.DropDown
        ShowResultsButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        EnterParamterEditField          %An Edit Field for Quantization Paramters Entry.
        EnterParamterEditFieldLabel     %A Label for the Paramters Entry Edit Field
        EnterParamtersButton % Description
    end


    methods (Access = private)

        % Value changed function: SelectaQuantizationTechniqueDropDown
        function SelectaQuantizationTechniqueDropDownValueChanged(app, event)
            selectedButton = app.SelectaQuantizationTechniqueDropDown.Value;
            if strcmpi(selectedButton , 'Uniform')
                delete(app.EnterParamterEditField)
                delete(app.EnterParamterEditFieldLabel)
                
                app.EnterParamterEditField = uieditfield(app.UIFigure, 'text');
                app.EnterParamterEditField.Position = [245 340 200 30];
                
                app.EnterParamterEditFieldLabel = uilabel(app.UIFigure);
                app.EnterParamterEditFieldLabel.HorizontalAlignment = 'right';
                app.EnterParamterEditFieldLabel.FontName = 'Calibri';
                app.EnterParamterEditFieldLabel.FontWeight = 'bold';
                app.EnterParamterEditFieldLabel.FontAngle = 'italic';
                app.EnterParamterEditFieldLabel.Position = [52 340 190 40];
                app.EnterParamterEditFieldLabel.Text = 'Enter L, m_p and Quantizer Type:';

            elseif strcmpi(selectedButton , 'Non-uniform')
                delete(app.EnterParamterEditField)
                delete(app.EnterParamterEditFieldLabel)
                app.EnterParamterEditField = uieditfield(app.UIFigure, 'text');
                app.EnterParamterEditField.Position =  [245 340 200 30];
                
                app.EnterParamterEditFieldLabel = uilabel(app.UIFigure);
                app.EnterParamterEditFieldLabel.HorizontalAlignment = 'left';
                app.EnterParamterEditFieldLabel.FontName = 'Calibri';
                app.EnterParamterEditFieldLabel.FontWeight = 'bold';
                app.EnterParamterEditFieldLabel.FontAngle = 'italic';
                app.EnterParamterEditFieldLabel.Position = [54 340 200 40];
                app.EnterParamterEditFieldLabel.Text = 'Enter u, L, m_p and Quantizer Type:';
          
            end
        end

        % Button pushed function: ShowResultsButton
        function ShowResultsButtonPushed(app, event)
           %This function applies mid-rise uniform quantization for an input signal, given the step size between levels and the number of levels.
            function Q_Signal = midRiseUniformQuantization(Signal, L, step_size)
                Q_Signal = zeros(length(Signal));
                l_max = L/2; 
                   for i = 1:length(Signal)
                       for l = 0 : (l_max-1)                                
                            if ((abs(Signal(i)) > l*step_size)&&(abs(Signal(i)) <= (l+1)*step_size))
                               if Signal(i) > 0 
                                   Q_Signal(i) = (l+0.5)*step_size;
                               elseif Signal(i) < 0
                                   Q_Signal(i) = -(l+0.5)*step_size;
                               elseif Signal(i) == 0
                                   Q_Signal(i) = 0;
                            
                               end
                            end
                       end                           
                   end
            end
        %#################################################################################################################################
            function Q_Signal = midTreadUniformQuantization(Signal, L, step_size)
                l_p = L/2 + 1;
                    l_n = L/2 - 1;
                    for i = 1:length(Signal)
                        if Signal(i) > 0
                            for l = 0: (l_p - 2)
                                if ((l*step_size < Signal(i))&&(Signal(i) <= (l+1)*step_size)&&(Signal(i) <= (l+0.5)*step_size))
                                    Q_Signal(i) = l*step_size;
                                elseif ((l*step_size <= Signal(i))&&(Signal(i) <= (l+1)*step_size)&&(Signal(i) >= (l+0.5)*step_size))
                                    Q_Signal(i) = (l+1)*step_size;
                                end
                            end
                        elseif Signal(i) < 0
                            
                            for l = 0: (l_n - 1)
                                if ((l*step_size < abs(Signal(i)))&&(abs(Signal(i)) <= (l+0.5)*step_size)&&((abs(Signal(i)) <= (l+1)*step_size)))
                                    Q_Signal(i) = -l*step_size;
                                else
                                    Q_Signal(i) = -(l+1)*step_size;
                                end
                            end 
                        else
                           Q_Signal(i) = 0; 
                        end
                    end
        end
        %#################################################################################################################################
  
            Paramters = split(app.EnterParamterEditField.Value, " ");
            Signal  = str2num(app.EntertheMessageSignalEditField.Value);
            TimeVec = str2num(app.EntertheTimeVectorEditField.Value);
            T_s = TimeVec(2) - TimeVec(1);
            
            if strcmpi(app.SelectaQuantizationTechniqueDropDown.Value, 'Uniform')   %If the quantization is uniform
                %Defining the Quantization Parameters.
                L = str2double(Paramters(1));
                m_p = str2double(Paramters(2));
                Qtype = Paramters(3);
                
                step_size = 2*m_p/L;
               
                if strcmpi(Qtype,'R')        %If the quantization type is mid-rise quantization.
                   Q_Signal = midRiseUniformQuantization(Signal, L, step_size);
                elseif strcmpi(Qtype,'T')      %If the quantization type is mid-tread quantization.
                    Q_Signal = midTreadUniformQuantization(Signal, L, step_size);
                end    
            elseif strcmpi(app.SelectaQuantizationTechniqueDropDown.Value, 'Non-uniform') 
                u = str2double(Paramters(1));
                L= str2double(Paramters(2));
                m_p = str2double(Paramters(3));
                Qtype = Paramters(4);
                
                step_size = 2*m_p/L;
                
                %Let's first use the mu law for signal compression.
                if u == 0       %If the mu is zero, apply uniform quantization on the input signal.
                    if strcmpi(Qtype,'R')        
                        Q_Signal = midRiseUniformQuantization(Signal, L, step_size);
                    elseif strcmpi(Qtype,'T')      
                        Q_Signal = midTreadUniformQuantization(Signal, L, step_size);
                    end 
                else
                   normalized_Sig = Signal/max(Signal);
                   compressed_Sig = zeros(length(normalized_Sig));
                
                   for i = 1:length(normalized_Sig)
                        if normalized_Sig(i) > 0
                             compressed_Sig(i) = log(1+u*normalized_Sig(i))/log(1+u);
                        elseif normalized_Sig(i) < 0
                             compressed_Sig(i) = -log(1+(u*abs(normalized_Sig(i))))/log(1+u);
                        else
                             compressed_Sig(i) = 0;
                        end
                   end 
                   un_compressed_Sig = max(Signal)*compressed_Sig;
                                
                   %Now, apply uniform quantization for the compressed signal.
                   if strcmpi(Qtype,'R')        %If the quantization type is mid-rise quantization.
                      Q_Signal = midRiseUniformQuantization(un_compressed_Sig, L, step_size);
                   elseif strcmpi(Qtype,'T')      %If the quantization type is mid-tread quantization.
                       Q_Signal = midTreadUniformQuantization(un_compressed_Sig, L, step_size);
                   end 
                end
            end
            
            %Holding the value of a quantiation level for T_s 
            t = TimeVec(1):T_s/100:TimeVec(length(TimeVec));
            Q_Sig2 = zeros(length(t));
            
            for i = 1:length(Q_Signal)
                  Q_Sig2(((i-1)*T_s <= t) & (t < i*T_s)) = Q_Signal(i);
            end 
            
            %Plotting the quantized signal and the original signal on the same graph.
            oness = ones(99);
            Quantized_Continueous_Sig = cat(2, transpose(Q_Sig2(:,1)), Q_Signal(length(Signal))*transpose(oness(:,1)));
            
            t_new = 0:length(Quantized_Continueous_Sig)-1; %New time vector for plotting the signals.
            
            
            %Interpolate the original signal for its length to mactch the length of the quantized signal, for proper plot drawing.
            Original_Continueous_sig = interp1(TimeVec, Signal, linspace(TimeVec(1),TimeVec(length(TimeVec)),length(t_new)),'spline'); 
            
            %Plot the original signal and the quantized signal on the same plot.
            c = TimeVec(length(TimeVec))/(length(Quantized_Continueous_Sig));
            
            app.UIAxes.cla;
            plot(app.UIAxes,c*t_new, Quantized_Continueous_Sig)
            hold(app.UIAxes, "on")
            plot(app.UIAxes, c*t_new, Original_Continueous_sig)
            legend(app.UIAxes, {'Quantized Signal','Original Signal'})
            
            %Finally, let's get the expected value of the quantization error.
            sqr_err = (Signal-transpose(Q_Signal(:,1))).^2;
            E_Q_err = mean(sqr_err(:,1));
            app.Label.Text = "The mean square quantization error is "+ string(E_Q_err);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EntertheTimeVectorEditFieldLabel = uilabel(app.UIFigure);
            app.EntertheTimeVectorEditFieldLabel.Text = 'Enter the Time Vector: ';
            app.EntertheTimeVectorEditField = uieditfield(app.UIFigure, 'text');
            app.EntertheMessageSignalEditFieldLabel = uilabel(app.UIFigure);
            app.EntertheMessageSignalEditFieldLabel.Text = 'Enter the Message Signal: ';
            app.EntertheMessageSignalEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Quantized Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, {'Amplitude'; ''})
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.SelectaQuantizationTechniqueDropDownLabel = uilabel(app.UIFigure);
            app.SelectaQuantizationTechniqueDropDownLabel.Text = 'Select a Quantization Technique';
            app.SelectaQuantizationTechniqueDropDown = uidropdown(app.UIFigure);
            app.SelectaQuantizationTechniqueDropDown.Items = {'Select', 'Uniform', 'Non-uniform'};
            app.SelectaQuantizationTechniqueDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectaQuantizationTechniqueDropDownValueChanged, true);
            app.SelectaQuantizationTechniqueDropDown.Value = 'Select';
            app.ShowResultsButton = uibutton(app.UIFigure, 'push');
            app.ShowResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowResultsButtonPushed, true);
            app.ShowResultsButton.Text = 'Show Results';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CIE_337_Project_II_Part_A

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end