classdef CIE_337_Project_II_Part_B < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ShowResultsButton            matlab.ui.control.Button
        SelectanOptionDropDownLabel  matlab.ui.control.Label
        SelectanOptionDropDown       matlab.ui.control.DropDown
        UIAxes                       matlab.ui.control.UIAxes
        SelectalinecodeforencodingDropDownLabel  matlab.ui.control.Label
        SelectalinecodeforencodingDropDown  matlab.ui.control.DropDown
        Label                        matlab.ui.control.Label
    end

    
    properties (Access = private)
      
        EnterNumberofBitsDropDownLabel;
        EnterNumberofBitsEditField;
        EnterNumberofBitsButton;
        EnterTbALabel;
        EnterTbAEditField;
        EnterStreamofBitsEditField;
        EnterStreamofBitsLabel;
        SelectaLineCodeDropDownLabel;
        SelectaLineCodeDropDown;
        
    end
    

    methods (Access = private)

        % Value changed function: SelectanOptionDropDown
        function SelectanOptionDropDownValueChanged(app, event)
           if app.SelectanOptionDropDown.Value == "Generate a random stream of bits"
                delete(app.EnterStreamofBitsLabel);
                delete(app.EnterStreamofBitsEditField);
                delete(app.EnterTbAEditField);
                delete(app.EnterTbALabel);
                
                app.EnterNumberofBitsDropDownLabel = uilabel(app.UIFigure);
                app.EnterNumberofBitsDropDownLabel.FontName = 'Calibri';
                app.EnterNumberofBitsDropDownLabel.FontWeight = 'bold';
                app.EnterNumberofBitsDropDownLabel.FontAngle = 'italic';
                app.EnterNumberofBitsDropDownLabel.Position = [37 410 180 22];
                app.EnterNumberofBitsDropDownLabel.Text = 'Enter the number of bits';    
                
                app.EnterNumberofBitsEditField = uieditfield(app.UIFigure, 'text');
                app.EnterNumberofBitsEditField.Position = [210 410 202 22];
                
                app.EnterTbALabel = uilabel(app.UIFigure);
                app.EnterTbALabel.FontName = 'Calibri';
                app.EnterTbALabel.FontWeight = 'bold';
                app.EnterTbALabel.FontAngle = 'italic';
                app.EnterTbALabel.Position = [37 372 220 22];
                app.EnterTbALabel.Text = 'Enter the bit duration and the amplitude'; 
                
                app.EnterTbAEditField = uieditfield(app.UIFigure, 'text');
                app.EnterTbAEditField.Position = [270 372 202 22];


            elseif app.SelectanOptionDropDown.Value == "Enter a Stream of bits"
                delete(app.EnterNumberofBitsDropDownLabel);
                delete(app.EnterNumberofBitsEditField);
                delete(app.EnterTbAEditField);
                delete(app.EnterTbALabel);
                
                app.EnterStreamofBitsLabel = uilabel(app.UIFigure);
                app.EnterStreamofBitsLabel.FontName = 'Calibri';
                app.EnterStreamofBitsLabel.FontWeight = 'bold';
                app.EnterStreamofBitsLabel.FontAngle = 'italic';
                app.EnterStreamofBitsLabel.Position = [37 410 180 22];
                app.EnterStreamofBitsLabel.Text = 'Enter a stream of bits';    
                
                app.EnterStreamofBitsEditField = uieditfield(app.UIFigure, 'text');
                app.EnterStreamofBitsEditField.Position = [210 410 202 22];
                
                app.EnterTbALabel = uilabel(app.UIFigure);
                app.EnterTbALabel.FontName = 'Calibri';
                app.EnterTbALabel.FontWeight = 'bold';
                app.EnterTbALabel.FontAngle = 'italic';
                app.EnterTbALabel.Position = [37 372 220 22];
                app.EnterTbALabel.Text = 'Enter the bit duration and the amplitude'; 
                
                app.EnterTbAEditField = uieditfield(app.UIFigure, 'text');
                app.EnterTbAEditField.Position = [270 372 202 22]; 
               
            end
            
        end

        % Button pushed function: ShowResultsButton
        function ShowResultsButtonPushed(app, event)
           %The encoder function inplementation
            function Encoder_Sig = Encoder(line_code_selection, bit_stream, Tb, A)
                Encoder_Sig = zeros(100*length(bit_stream));
                time_vec = transpose(linspace(0, length(bit_stream)*Tb, 100*length(bit_stream)));
                
                if strcmpi(line_code_selection, 'Unipolar NRZ')
                    
                    for i = 1:length(bit_stream)
                        if bit_stream(i) == 0
                            Encoder_Sig(((i-1)*Tb <= time_vec) & (time_vec < i*Tb)) = 0;
                        elseif bit_stream(i) == 1
                            Encoder_Sig(((i-1)*Tb <= time_vec) & (time_vec < i*Tb)) = A;
                        end
                    end
                    plot(app.UIAxes, time_vec, Encoder_Sig)
                    title(app.UIAxes, "Unipolar-NRZ-Encoded Signal ")
                     
                elseif strcmpi(line_code_selection, 'Polar RZ ')
                     
                     for i = 1:length(bit_stream)
                        if bit_stream(i) == 0
                           Encoder_Sig(((i-1)*Tb <= time_vec) & (time_vec < (i-0.5)*Tb)) = -A;
                        elseif bit_stream(i) == 1
                           Encoder_Sig(((i-1)*Tb <= time_vec) & (time_vec < (i-0.5)*Tb)) = A;
                        end
                        Encoder_Sig(((i-0.5)*Tb <= time_vec) & (time_vec < i*Tb)) = 0;
                    end
                    plot(app.UIAxes, time_vec, Encoder_Sig)
                    title(app.UIAxes, "Polar-RZ-Encoded Signal ")           
                elseif strcmpi(line_code_selection, 'Alternate mark inversion')
                     flg = 0;
                     for i = 1:length(bit_stream)
                        if bit_stream(i) == 0
                            Encoder_Sig(((i-1)*Tb <= time_vec) & (time_vec < i*Tb)) = 0;
                        elseif (bit_stream(i) == 1) && (mod(flg,2) == 0)
                             Encoder_Sig(((i-1)*Tb <= time_vec) & (time_vec < (i-0.5)*Tb)) = A;
                            flg =flg+1;
                        elseif (bit_stream(i) == 1) && (mod(flg,2) ~= 0)
                            Encoder_Sig(((i-1)*Tb <= time_vec) & (time_vec < (i-0.5)*Tb))= -A;
                            flg =flg+1;
                        end
                        Encoder_Sig(((i-0.5)*Tb <= time_vec) & (time_vec < i*Tb)) = 0;
                     end                   
                     plot(app.UIAxes, time_vec, Encoder_Sig)
                     title(app.UIAxes, "AMI-Encoded Signal ")
                elseif strcmpi(line_code_selection, 'Manchester')
                     for i = 1:length(bit_stream)
                        if bit_stream(i) == 0
                           Encoder_Sig(((i-1)*Tb <= time_vec) & (time_vec < (i-0.5)*Tb)) = -A;
                           Encoder_Sig(((i-0.5)*Tb <= time_vec) & (time_vec < i*Tb)) = A;
                        elseif bit_stream(i) == 1
                           Encoder_Sig(((i-1)*Tb <= time_vec) & (time_vec < (i-0.5)*Tb)) = A;
                           Encoder_Sig(((i-0.5)*Tb <= time_vec) & (time_vec < i*Tb)) = -A;
                        end
                        
                     end
                     plot(app.UIAxes, time_vec, Encoder_Sig)
                     title(app.UIAxes, "Machester-Encoded Signal ")
                end
            end
            
            if app.SelectanOptionDropDown.Value == "Generate a random stream of bits"
                N = str2double(app.EnterNumberofBitsEditField.Value);
                bit_stream = randi([0 1], N);
                str_bit = "";
                for i = 1:length(bit_stream(:,1))
                    str_bit = str_bit+" "+string(bit_stream(i));
                end
                app.Label.Text = 'The Random Signal is '+str_bit;
                
                parameters = split(app.EnterTbAEditField.Value, " ");
                Tb = str2double(parameters(1));
                A = str2double(parameters(2));
                line_code_selection = app.SelectalinecodeforencodingDropDown.Value;
                
                Encoder(line_code_selection, bit_stream, Tb, A)
                
            elseif app.SelectanOptionDropDown.Value == "Enter a Stream of bits"
                bit_stream = str2double(split(app.EnterStreamofBitsEditField.Value," "));
                parameters = split(app.EnterTbAEditField.Value, " ");
                Tb = str2double(parameters(1));
                A = str2double(parameters(2));
                line_code_selection = app.SelectalinecodeforencodingDropDown.Value;
                
                Encoder(line_code_selection, bit_stream, Tb, A)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ShowResultsButton = uibutton(app.UIFigure, 'push');
            app.ShowResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowResultsButtonPushed, true);
            app.ShowResultsButton.Text = {'Show Results'; ''};
            app.SelectanOptionDropDownLabel = uilabel(app.UIFigure);
            app.SelectanOptionDropDownLabel.Text = 'Select an Option';
            app.SelectanOptionDropDown = uidropdown(app.UIFigure);
            app.SelectanOptionDropDown.Items = {'Select', 'Generate a random stream of bits', 'Enter a Stream of bits'};
            app.SelectanOptionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectanOptionDropDownValueChanged, true);
            app.SelectanOptionDropDown.Value = 'Select';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Line Code Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            app.SelectalinecodeforencodingDropDownLabel = uilabel(app.UIFigure);
            app.SelectalinecodeforencodingDropDownLabel.Text = 'Select a line code for encoding';
            app.SelectalinecodeforencodingDropDown = uidropdown(app.UIFigure);
            app.SelectalinecodeforencodingDropDown.Items = {'Select', 'Unipolar NRZ', 'Polar RZ ', 'Alternate mark inversion', 'Manchester'};
            app.SelectalinecodeforencodingDropDown.Value = 'Select';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CIE_337_Project_II_Part_B

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end